classdef gait_analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        axes2                 matlab.ui.control.UIAxes
        axes3                 matlab.ui.control.UIAxes
        axes4                 matlab.ui.control.UIAxes
        axes11                matlab.ui.control.UIAxes
        axes12                matlab.ui.control.UIAxes
        axes13                matlab.ui.control.UIAxes
        axes8                 matlab.ui.control.UIAxes
        axes9                 matlab.ui.control.UIAxes
        axes10                matlab.ui.control.UIAxes
        axes1                 matlab.ui.control.UIAxes
        pushbutton_get_file1  matlab.ui.control.Button
        text5                 matlab.ui.control.Label
        pushbutton10          matlab.ui.control.Button
        text6                 matlab.ui.control.Label
        text7                 matlab.ui.control.Label
        text8                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gait_analysis_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.output = hObject;
            guidata(hObject, handles);
            
            axes(handles.axes11);
            imshow('1angle.png');
            axes(handles.axes12);
            imshow('2angle.png');
            axes(handles.axes13);
            imshow('3angle.png');
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.pushbutton_get_file1, 'Enable', 'On');
            set(handles.pushbutton10, 'Enable', 'Off');
            cla(handles.axes1,'reset');
            cla(handles.axes2,'reset');
            cla(handles.axes3,'reset');
            cla(handles.axes4,'reset');
            cla(handles.axes8,'reset');
            cla(handles.axes9,'reset');
            cla(handles.axes10,'reset');
        end

        % Button pushed function: pushbutton_get_file1
        function pushbutton_get_file1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            file_name=uigetfile({'*.mp4;*.AVI;'},'Choose file');
            if file_name ~= 0
                set(handles.pushbutton_get_file1, 'Enable', 'Off');
                video = VideoReader(file_name);
                frameNo = 1;
                [angle1, user_cent] = main_f(handles,video,frameNo);
                assignin('base','angle1',angle1);
                assignin('base','user_cent',user_cent);
                step_loop(handles,angle1,1,user_cent);
            end
            set(handles.pushbutton10, 'Enable', 'On');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'gait_analysis';
            app.figure1.Tag = 'figure1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.axes4 = uiaxes(app.figure1);
            app.axes4.Tag = 'axes4';
            app.axes11 = uiaxes(app.figure1);
            app.axes11.Tag = 'axes11';
            app.axes12 = uiaxes(app.figure1);
            app.axes12.Tag = 'axes12';
            app.axes13 = uiaxes(app.figure1);
            app.axes13.Tag = 'axes13';
            app.axes8 = uiaxes(app.figure1);
            app.axes8.Tag = 'axes8';
            app.axes9 = uiaxes(app.figure1);
            app.axes9.Tag = 'axes9';
            app.axes10 = uiaxes(app.figure1);
            app.axes10.Tag = 'axes10';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.pushbutton_get_file1 = uibutton(app.figure1, 'push');
            app.pushbutton_get_file1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_get_file1_Callback, true);
            app.pushbutton_get_file1.Tag = 'pushbutton_get_file1';
            app.pushbutton_get_file1.Text = 'Upload and start';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'VIDEO BASED GAIT ANALYSING SYSTEM ';
            app.pushbutton10 = uibutton(app.figure1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Text = 'Reset';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Hip Angle';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Knee Angle';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Foot Angle';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gait_analysis_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)gait_analysis_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end